classdef EEG_visualize < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        CheckBox_5          matlab.ui.control.CheckBox
        CheckBox_4          matlab.ui.control.CheckBox
        CheckBox_3          matlab.ui.control.CheckBox
        CheckBox_2          matlab.ui.control.CheckBox
        CheckBox            matlab.ui.control.CheckBox
        ValidateButton      matlab.ui.control.Button
        SwitchsignalButton  matlab.ui.control.Button
        TypeButton          matlab.ui.control.Button
        Label_6             matlab.ui.control.Label
        Label_5             matlab.ui.control.Label
        Label_4             matlab.ui.control.Label
        Label_3             matlab.ui.control.Label
        Label_2             matlab.ui.control.Label
        Label               matlab.ui.control.Label
        PreviousButton      matlab.ui.control.Button
        NextButton          matlab.ui.control.Button
        UIAxes_5            matlab.ui.control.UIAxes
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes_3            matlab.ui.control.UIAxes
        UIAxes_4            matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        start_index=1
        EEG_graph
        essais_rejected=dictionary()
    end  
    
    methods (Access = private)
      
        function updatePlot(app)
            
            plots=[app.UIAxes,app.UIAxes_2,app.UIAxes_3,app.UIAxes_4,app.UIAxes_5];
            labels=[app.Label,app.Label_2,app.Label_3,app.Label_4,app.Label_5];
            

            for ii = 1:5
                cla(plots(ii));
                labels(ii).Text='';
                colorbar(plots(ii), 'off');
            end

            app.colorlabels()

            % Paramètres temporels
            Fs = 512; % Fréquence d'échantillonnage en Hz
            % Créer le vecteur de temps en secondes
            temps_en_secondes = (0:size(app.EEG_graph, 2) - 1) / Fs;

            for ii = app.start_index:min(app.start_index+4,size(app.EEG_graph,1))
                if strcmp(app.TypeButton.Text, 'Time-frequency')
                    % Afficher les signaux bruts
                    plot(plots(ii-app.start_index+1),temps_en_secondes, app.EEG_graph(ii, :));
                    labels(ii-app.start_index+1).Text = compose('Essai %d', ii);
                    % Ajuster automatiquement l'axe y
                    ylim(plots(ii-app.start_index+1), 'auto');
                    
                else
                    % Afficher les spectrogrammes
                                        
                        % Paramètres de la spectrogramme
                        window = hamming(256); % Fenêtre de Hamming
                        noverlap = 250; % Chevauchement
                
                        % Calcul de la spectrogramme
                        specData = spectrogram(app.EEG_graph(ii, :), window, noverlap, [], Fs, 'yaxis');
                        
                        % Créer le vecteur de temps en secondes pour la spectrogramme
                        temps_en_secondes_spec = (0:size(specData, 2) - 1) * (256 - noverlap) / Fs;

                        % Ajuster le vecteur de temps pour qu'il commence à 0
                        temps_en_secondes_spec = temps_en_secondes_spec + temps_en_secondes_spec(2);

                        % Afficher la spectrogramme avec imagesc
                        imagesc(plots(ii-app.start_index+1), temps_en_secondes_spec, [0, 50], abs(specData));
                        % imagesc(plots(ii-app.start_index+1),temps_en_secondes, abs(specData));  % Utilisez 'abs' pour obtenir la magnitude
                        xlabel(app.UIAxes_5, 'Temps (s)');
                        ylabel(plots(ii-app.start_index+1), 'Fréquence (Hz)');
                        
                        % % Ajuster les limites des axes pour afficher la totalité du contenu
                        % xlim(plots(ii-app.start_index+1), [0, size(app.EEG_graph, 2)]);  % Ajustez en fonction de la taille de votre signal
                       % Inverser l'axe y
                        set(plots(ii-app.start_index+1), 'YDir', 'normal');
                        ylim(plots(ii-app.start_index+1), [0, 50]);
                    
                        % Ajouter une barre de couleur
                        colorbar(plots(ii-app.start_index+1));  
                        colormap(plots(ii-app.start_index+1), 'jet');

                    labels(ii-app.start_index+1).Text = compose('Essai %d', ii);

                end
            end

        end

        function r = essaiExist(app,i)
            r = i<=size(app.EEG_graph,1);
        end

        function colorlabels(app)
            labels=[app.Label,app.Label_2,app.Label_3,app.Label_4,app.Label_5];

            remise à zéro
            
            for ii = 1:5

                if not(app.essaiExist(app.start_index+ii-1))
                   return
                end

                if app.essais_rejected(app.start_index+ii-1)
                    labels(ii).BackgroundColor = [1, 0, 0]; % Rouge
                else
                    labels(ii).BackgroundColor = app.UIFigure.Color; % Transparent
                end
                
            end
        end

        function update_checkbox(app)
            checkboxes=[app.CheckBox,app.CheckBox_2,app.CheckBox_3,app.CheckBox_4,app.CheckBox_5];
            
            remise à zéro

            for ii = 1:5
                
                if not(app.essaiExist(app.start_index+ii-1))
                   return
                end

                if app.essais_rejected(app.start_index+ii-1)
                    checkboxes(ii).Value=true; % Cochée
                else
                    checkboxes(ii).Value=false; % Décochée
                end
                
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Ajoutez cette ligne au début de la méthode startupFcn
            % variableName = inputdlg('Entrez le nom de la variable à étudier:', 'Choix de la variable', [1 50]);

            % % Utilisez la variableName pour extraire la variable du workspace
            % if ~isempty(variableName)
                app.EEG_graph = evalin('base', 'EEG');
                app.Label_6.Text = 'EEG Visualize';  % Mettez à jour le titre avec le nom de la variable
                
                for i = 1:size(app.EEG_graph,1)
                    app.essais_rejected(i)=false;
                end

                updatePlot(app);

            % else
            %     % Gérez le cas où l'utilisateur a annulé la boîte de dialogue
            %     disp('Opération annulée par l''utilisateur.');
            %     % Vous pouvez également fermer l'application si l'annulation n'est pas autorisée
            % end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)

            if app.start_index+5<size(app.EEG_graph,1)            
                app.start_index= app.start_index+5;
            end

            updatePlot(app);
            update_checkbox(app)                
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            app.start_index=max(app.start_index-5,1);
            updatePlot(app)
            update_checkbox(app)
        end

        % Button pushed function: TypeButton
        function TypeButtonPushed(app, event)
            if strcmp(app.TypeButton.Text, 'Time-frequency')
                app.TypeButton.Text = 'Raw signal';
                updatePlot(app)
            else
               app.TypeButton.Text = 'Time-frequency' ;
               updatePlot(app)
            end

        end

        % Button pushed function: SwitchsignalButton
        function SwitchsignalButtonPushed(app, event)
            if strcmp(app.Label_6.Text, 'EEG Visualize')
                app.EEG_graph = evalin('base', 'EMG');
                app.Label_6.Text = 'EMG Visualize';  % Mettez à jour le titre avec le nom de la variable
                updatePlot(app);
            else
                app.EEG_graph = evalin('base', 'EEG');
                app.Label_6.Text = 'EEG Visualize';  % Mettez à jour le titre avec le nom de la variable
                updatePlot(app);
            end
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            
           if not(app.essaiExist(app.start_index))
             return
           end
           
           app.essais_rejected(app.start_index)=app.CheckBox.Value;

           colorlabels(app)
               
            
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            if not(app.essaiExist(app.start_index+1))
             return
           end
           
           app.essais_rejected(app.start_index+1)=app.CheckBox_2.Value;
           colorlabels(app)
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            if not(app.essaiExist(app.start_index+2))
             return
           end
           
           app.essais_rejected(app.start_index+2)=app.CheckBox_3.Value;
           colorlabels(app)
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            if not(app.essaiExist(app.start_index+3))
             return
           end
           
           app.essais_rejected(app.start_index+3)=app.CheckBox_4.Value;
           colorlabels(app)
        end

        % Value changed function: CheckBox_5
        function CheckBox_5ValueChanged(app, event)
            if not(app.essaiExist(app.start_index+4))
             return
           end
           
           app.essais_rejected(app.start_index+4)=app.CheckBox_5.Value;
           colorlabels(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            ylabel(app.UIAxes, 'µV')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_4, 'µV')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_3, 'µV')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_2, 'µV')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_5, 'Time (s)')
            ylabel(app.UIAxes_5, 'µV')
            zlabel(app.UIAxes_5, 'Z')
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.Label = uilabel(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'Titre';
            app.TypeButton = uibutton(app.UIFigure, 'push');
            app.TypeButton.ButtonPushedFcn = createCallbackFcn(app, @TypeButtonPushed, true);
            app.TypeButton.Text = 'Time-frequency';
            app.SwitchsignalButton = uibutton(app.UIFigure, 'push');
            app.SwitchsignalButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchsignalButtonPushed, true);
            app.SwitchsignalButton.Text = 'Switch signal';
            app.ValidateButton = uibutton(app.UIFigure, 'push');
            app.ValidateButton.Text = 'Validate';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.UIFigure);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = '';
            app.CheckBox_5 = uicheckbox(app.UIFigure);
            app.CheckBox_5.ValueChangedFcn = createCallbackFcn(app, @CheckBox_5ValueChanged, true);
            app.CheckBox_5.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEG_visualize

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end